classdef SignalProperties < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        SignalGenerator                matlab.ui.Figure
        MagVSFreq                      matlab.ui.control.UIAxes
        PhaseVSFreq                    matlab.ui.control.UIAxes
        AmpVSTime                      matlab.ui.control.UIAxes
        CloseButton                    matlab.ui.control.Button
        InsertaudiofileButton          matlab.ui.control.Button
        SaveSignalButton               matlab.ui.control.Button
        DCMenu                         matlab.ui.container.Panel
        DutyCycleInf                   matlab.ui.control.Label
        PercentageLabel                matlab.ui.control.Label
        DutyCycleEditFieldLabel        matlab.ui.control.Label
        DutyCycleEditField             matlab.ui.control.NumericEditField
        DutyCycleKnobLabel             matlab.ui.control.Label
        DutyCycleKnob                  matlab.ui.control.Knob
        Image                          matlab.ui.control.Image
        RecordButton                   matlab.ui.control.Button
        FrequencyEditFieldLabel        matlab.ui.control.Label
        FrequencyEditFieldLabel_2      matlab.ui.control.Label
        FrequencyEditFieldLabel_3      matlab.ui.control.Label
        FrequencyEditFieldLabel_4      matlab.ui.control.Label
        ResetButton                    matlab.ui.control.Button
        ChooseawaveformListBoxLabel    matlab.ui.control.Label
        SignalTypeBox                  matlab.ui.control.ListBox
        FrequencyFcEditFieldLabel      matlab.ui.control.Label
        FrequencyEditField             matlab.ui.control.NumericEditField
        AmplitudeAEditFieldLabel       matlab.ui.control.Label
        AmplitudeEditField             matlab.ui.control.NumericEditField
        SamplingFrequencyFsEditFieldLabel  matlab.ui.control.Label
        SamplingFrequencyEditField     matlab.ui.control.NumericEditField
        DurationtEditFieldLabel        matlab.ui.control.Label
        DurationEditField              matlab.ui.control.NumericEditField
        FilterTypeDropDownLabel        matlab.ui.control.Label
        FilterTypeDropDown             matlab.ui.control.DropDown
        PlayButton                     matlab.ui.control.Button
        FilterSettings                 matlab.ui.container.Panel
        fH                             matlab.ui.container.Panel
        SecondCutoffEditField          matlab.ui.control.NumericEditField
        SecondCutoffFrequencyLabel     matlab.ui.control.Label
        SecondCutoffSlider             matlab.ui.control.Slider
        FilterOrderEditField           matlab.ui.control.NumericEditField
        LowCutoffEditField             matlab.ui.control.NumericEditField
        LowCutoffSlider                matlab.ui.control.Slider
        ApplyButton                    matlab.ui.control.Button
        FilterOrderEditFieldLabel      matlab.ui.control.Label
        CutoffFrequencyLabel           matlab.ui.control.Label
        RecordtimeEditFieldLabel       matlab.ui.control.Label
        RecordtimeEditField            matlab.ui.control.NumericEditField
        secLabel                       matlab.ui.control.Label
        StopButton                     matlab.ui.control.Button
        Minus                          matlab.ui.control.Button
        AmpVal                         matlab.ui.control.NumericEditField
        Plus                           matlab.ui.control.Button
        AddSubrstractAmplitudedBLabel  matlab.ui.control.Label
    end

    properties (Access = public)
        signal = NaN; % Saved signal.
        f_signal = NaN % Saved filtered signal (when filtered).
        duration = NaN; % Length in time of a signal.
        sampling_f;  % Sampling frequency of signal [Hz].
        filtercoef; % Saved coefficients of generated FIR filter.

        % Data vectors for x-Axis
        time;                 % Time points corresponding to signal [sec].
        theta                 % Points for normalized frequency for frequency domain.

        % Data for plot
        low_cutoff_ruler        % Low cutoff frequency ruler for plot. 
        high_cutoff_ruler       % High cutoff frequency ruler for plot.
        filter_magnitude        % Magnitude for frequency domain plot.
        filter_angle            % Angle for frequency domain plot.

            % Color shortcut for plots
            ColorFrequencyResponse = '#FF00FF';%Pink  
            ColorSignalUnfiltered = '#0000FF'; %Blue
            ColorSignalFiltered = '#77AC30'; %Green
            ColorCutoffFrequency = '#EDB120'; %Yellow

        AudioFormats = {'*.wav';'*.ogg';'*.flac';'*.au';'*.mp3';'*.m4a';'*.mp4';'*.mid'}; %load and save audio formats
        SaveFormats = {'*.wav';'*.ogg';'*.flac';'*.m4a';'*.mp4'}; %load and save audio formats

        FirstClick = true; % Flag made for apply button for applying filter on signal

    end


    methods (Access = private)

        function ScreenAdjustments(app)
            set(0,'units','pixels');        % Change units to pixels
            pixels = get(0, 'ScreenSize');  % Get screen resolution
            ScreenWidth = pixels(3);
            ScreenHeight = pixels(4);
            AppWidth = app.SignalGenerator.Position(3);
            AppHeight = app.SignalGenerator.Position(4);
            
            app.SignalGenerator.Position(1) = (ScreenWidth - AppWidth) / 2;
            app.SignalGenerator.Position(2) = (ScreenHeight - AppHeight) / 2;
        end 


        function ResetParameters(app)
            cla(app.AmpVSTime);
            cla(app.MagVSFreq); % Clear magnitude and phase plots.
            cla(app.PhaseVSFreq);
            legend(app.MagVSFreq, 'off');
            legend(app.PhaseVSFreq, 'off');
            legend(app.MagVSFreq, 'off');
            legend(app.PhaseVSFreq, 'off');
            app.filtercoef = 0;
            app.SignalTypeBox.Value = app.SignalTypeBox.Items(1);
            app.DCMenu.Visible = 'off'; 
            app.FrequencyEditField.Value = 10;
            app.AmplitudeEditField.Value = 10;
            app.SamplingFrequencyEditField.Value = 1000;
            app.DurationEditField.Limits = [1/app.SamplingFrequencyEditField.Value inf];
            app.DurationEditField.Value = 1;
            app.FilterOrderEditField.Value = 50;
            app.LowCutoffEditField.Value = 0.5;
            app.SecondCutoffEditField.Value = 0.7;
            app.signal = NaN;
            app.f_signal = NaN;
            app.LowCutoffSlider.Value = app.LowCutoffEditField.Value;
            app.SignalTypeBox.Value = app.SignalTypeBox.Items(1);
            app.LowCutoffEditField.Value = 0.5;
            app.FilterTypeDropDown.Value = 'No Filter';
            % Delete old filter plots from graphs if exist
            if ~isempty(app.filter_magnitude)
                delete(app.filter_magnitude);
            end
            if ~isempty(app.filter_angle)
                delete(app.filter_angle);
            end
            app.FirstClick = true;
        end

        function CreateSignalWave(app)
            % Generate signal according to parameters
            A = app.AmplitudeEditField.Value;
            F = app.FrequencyEditField.Value;
            Fs = app.SamplingFrequencyEditField.Value;
            T = app.DurationEditField.Value;
            DC = app.DutyCycleEditField.Value;
            SignalType = app.SignalTypeBox.Value;

            % Save parameters to global variables
            app.signal = NaN(1, floor(T*Fs));
            app.f_signal = NaN(1, floor(T*Fs)); 
            app.sampling_f = Fs;
            app.duration = T;
            app.time = [0:1/Fs:T-1/Fs];
            

            % Create signal according to parameters and type
            switch SignalType
                case "Sin"
                    app.Image.ImageSource = 'Waveforms-Sine.jpg'; %Change picture to current type
                    app.DCMenu.Visible = 'off'; %Hide DC menu
                    app.signal = sin(2*pi*F*app.time); %Create sin signal
                case "Sawtooth"
                     app.Image.ImageSource = 'Waveforms-Saw.jpg'; %Change picture to current type
                     app.DCMenu.Visible = 'off'; %Hide DC menu
                     app.signal = sawtooth(2*pi*F*app.time); %Create sawtooth signal
                case "Square"
                    app.Image.ImageSource = 'Waveforms-Square.jpg'; %Change picture to current type
                    app.DCMenu.Visible = 'on'; %Reveal DC menu
                    app.signal = square(2*pi*F*app.time,DC); %Create square signal
                case "Triangle"
                    app.Image.ImageSource = 'Waveforms-Triangle.jpg'; %Change picture to current type
                    app.DCMenu.Visible = 'off'; %Hide DC menu
                    app.signal = sawtooth(2*pi*F*app.time, 0.5); %Create triangle signal
            end
            
            % Add amplitude to signal
            app.signal = A*app.signal;
            
            % Update plot
            Title = "Amplitude VS Time (Signal Type = " + SignalType + ", Fs = " + num2str(Fs) + " Hz, Fc = " + num2str(F); %add title
            if SignalType == "Square"
                Title = Title + ", DC = " + num2str(DC) + "%)"; %append DC info for square signal type
            else
                Title = Title + ")";
            end
            title(app.AmpVSTime, Title) %add title for signal in time domain plot.
            app.AmpVSTime.XLim = [0 app.duration];
        end


        function ChooseFilterType(app)

            % Frequency and magnitude calculated according to filter type.
            % f = Vector of frequency points in the range from 0 to 1, where 1 corresponds to the Nyquist frequency.
            % m = Vector containing the desired magnitude response at each of the points specified in f.

            switch app.FilterTypeDropDown.Value
                case "No Filter"
                    cla(app.MagVSFreq); % Clear magnitude and phase plots.
                    cla(app.PhaseVSFreq);
                    legend(app.MagVSFreq, 'off');
                    legend(app.PhaseVSFreq, 'off');
    
                    app.FilterSettings.Visible = 'off'; %Hide filter settings menu
                    app.fH.Visible = 'off';
                    
                     
                    return;
                case "Low Pass Filter"
                    app.FilterSettings.Visible = 'on';  %Reveal filter settings menu.
                    app.fH.Visible = 'off';             %Hide high cutoff frequecy field.
                    app.CutoffFrequencyLabel.Text = 'Cutoff Frequency'; %Change label name for cutoff frequency according to filter.
                    f = [0 app.LowCutoffEditField.Value app.LowCutoffEditField.Value 1];
                    m = [1 1 0 0];
                case "High Pass Filter"
                    app.FilterSettings.Visible = 'on';  %Reveal filter settings menu.
                    app.fH.Visible = 'off';             %Hide high cutoff frequecy field.
                    app.CutoffFrequencyLabel.Text = 'Cutoff Frequency'; %Change label name for cutoff frequency according to filter.
                    f = [0 app.LowCutoffEditField.Value app.LowCutoffEditField.Value 1];
                    m = [0 0 1 1];
                case "Band Pass Filter"
                    app.FilterSettings.Visible = 'on';  %Reveal filter settings menu.
                    app.fH.Visible = 'on';              %Reveal high cutoff frequecy field.
                    app.CutoffFrequencyLabel.Text = '1st Cutoff Frequency'; %Change label name for cutoff frequency according to filter.
                    f = [0 app.LowCutoffEditField.Value app.LowCutoffEditField.Value app.SecondCutoffEditField.Value app.SecondCutoffEditField.Value 1];
                    m = [0 0 1 1 0 0];
                case "Band Stop Filter"
                    app.FilterSettings.Visible = 'on';  %Reveal filter settings menu.
                    app.fH.Visible = 'on';              %Reveal high cutoff frequecy field.
                    app.CutoffFrequencyLabel.Text = '1st Cutoff Frequency'; %Change label name for cutoff frequency according to filter.
                    f = [0 app.LowCutoffEditField.Value app.LowCutoffEditField.Value app.SecondCutoffEditField.Value app.SecondCutoffEditField.Value 1];
                    %define magnitude
                    m = [1 1 0 0 1 1];
            end
            n = app.FilterOrderEditField.Value; %Filter order
            
            % Calculate filter coefficients
            try % If calculation succeeds, proceed with coefficients
                app.filtercoef = fir2(n, f, m);
            catch 
                uiwait(errordlg("Filter creation failed!"));
                app.filtercoef = []; % Delete coefficients for corrupted filter
            end

            PlotSignalFrequencyDomain(app);
            PlotFilter(app);
            PlotCutoff(app);
        end


        function PlotSignalTimeDomain(app)
            % Get signal properties
            % Max amplitude for time domain plot limits
            A = max(max(abs(app.signal),[], 'all'), max(abs(app.f_signal),[], 'all')); 
            if A == 0
                A = 1;
            end
            app.duration = ceil(app.duration);
            T = app.duration;
            % Plot signals
            hold(app.AmpVSTime,'off'); % Delete previous signals
            if size(app.signal, 1) == 2 % 2-channel stereo signal
                plot(app.AmpVSTime, app.time, app.signal(1,:), 'Color', app.ColorCutoffFrequency, 'DisplayName', 'Left Signal (Unfiltered)');
                hold(app.AmpVSTime,'on');
                plot(app.AmpVSTime, app.time, app.signal(2,:), 'Color', app.ColorSignalUnfiltered, 'DisplayName', 'Right Signal (Unfiltered)');
                if all(~isnan(app.f_signal)) % Signal was filtered
                    hold(app.AmpVSTime,'on');
                    plot(app.AmpVSTime, app.time, app.f_signal(1,:), 'Color', app.ColorCutoffFrequency, 'DisplayName', 'Left Signal (Filtered)');
                    hold(app.AmpVSTime,'on');
                    plot(app.AmpVSTime, app.time, app.f_signal(2,:), 'Color', app.ColorSignalFiltered, 'DisplayName', 'Right Signal (Filtered)')
                end
            else % 1-channel mono signal
                plot(app.AmpVSTime, app.time, app.signal, 'Color', app.ColorSignalUnfiltered, 'DisplayName', 'Signal (Unfiltered)');
                if all(~isnan(app.f_signal)) % Signal was filtered 
                    hold(app.AmpVSTime,'on');
                    plot(app.AmpVSTime, app.time, app.f_signal, 'Color', app.ColorSignalFiltered, 'DisplayName', 'Signal (Filtered)');
                end
            end
            app.AmpVSTime.YLim = [-2*ceil(A) 2*ceil(A)];
            legend(app.AmpVSTime);
        end

        function PlotSignalFrequencyDomain(app)
                       

            % Get signal magnitude and angle in frequency domain.
            N = 2^nextpow2(length(app.signal));
            N = min(2^18, N); % Limit fft order to run smoother.
            
            
            SignalFrequencyDomain = CompressAndFFT(app, app.signal, N);


            % Save only rounded half of signal because of the symmetry.
            SignalFrequencyDomain = SignalFrequencyDomain(:,1:ceil(length(SignalFrequencyDomain)/2)); 
            % Do the same for filtered signal.
            FilteredSignalFrequencyDomain = CompressAndFFT(app, app.f_signal, N);

            FilteredSignalFrequencyDomain = FilteredSignalFrequencyDomain(:,1:ceil(length(FilteredSignalFrequencyDomain)/2));
            
            % Calculate magnitude in [dB]
            Magnitude = 20*log10(abs(SignalFrequencyDomain)); % Magnitude [dB].
            FilteredMagnitude = 20*log10(abs(FilteredSignalFrequencyDomain)); % Filtered magnitude [dB].
            % Calculate linear angle in degrees
            Phase = 180 * unwrap(angle(SignalFrequencyDomain)); %Phase [Degrees]
            FilteredPhase = 180 * unwrap(angle(FilteredSignalFrequencyDomain)); %Filtered Phase [Degrees]
            % Calculate x axis values for normalized frequency domain.
            app.theta = (0:pi/min(length(SignalFrequencyDomain), length(FilteredSignalFrequencyDomain)):pi-pi/length(SignalFrequencyDomain)) / pi;
            
            hold(app.MagVSFreq, 'off'); % To delete previous signals from graph
            hold(app.PhaseVSFreq, 'off');     % To delete previous signals from graph

            if size(Magnitude, 1) == 1 % Mono signal
                PlotMonoSignalFreqDomain(app, Magnitude, FilteredMagnitude, Phase, FilteredPhase);
            end

            if size(Magnitude, 1) == 2 % Stereo signal
                PlotStereoSignalFreqDomain(app, Magnitude, FilteredMagnitude, Phase, FilteredPhase);

            end
            xlim(app.MagVSFreq, [0 1]);
            xlim(app.PhaseVSFreq, [0 1]);
            legend(app.MagVSFreq);
            legend(app.PhaseVSFreq);

        end

            function results = CompressAndFFT(app, Signal, N)
                    flag = true;
                    while flag
                    try
                        results = fft(Signal, N, 2); %Fast Fourier transform.
                        flag = false;
                        %Recalculate in case that the signal compressed.
                        app.duration = length(Signal) / app.sampling_f;
                        app.time = [0 : 1/app.sampling_f : app.duration - 1/app.sampling_f];
                    catch
                        %Compress signals if fft is too complex.
                        Signal = Signal(1:100:end) % Compress signal by keeping every 10'th cell and delete all the rest;
                    end
                    end
            end   

            function PlotFilter(app)
            % Calculate magnitude and angle of filter from FIR coefficients
            [h,~] = freqz(app.filtercoef, [1], length(app.theta));
            Magnitude = 20 * log10(abs(h));
            Phase = 180 * unwrap(angle(h));
                        
            % Add filter plots to graphs without deleting existing plots

            if app.FilterTypeDropDown.Value ~= "No Filter"
                hold(app.MagVSFreq, 'on');
                hold(app.PhaseVSFreq, 'on');
                app.filter_magnitude = plot(app.MagVSFreq, app.theta, Magnitude, "Color", app.ColorFrequencyResponse, "DisplayName", "Filter Response");
                app.filter_angle = plot(app.PhaseVSFreq, app.theta, Phase, "Color", app.ColorFrequencyResponse, "DisplayName", "Filter Response");
            end


            % Plot rulers to help visualize cutoff frequencies and update sliders
            % Get flags for filter with low cutoff frequency or high cutoff frequency
            FilterType = app.FilterTypeDropDown.Value;
            AnyFilter = FilterType ~= "No Filter"; % Any filter has at least one cutoff frequency
            TwoCutoffs = FilterType == "Band Pass Filter" || FilterType == "Band Stop Filter"; % Only BP&BS have 2 cutoff frequencies
            
            % Delete previous cutoff frequencies
            if ~isempty(app.low_cutoff_ruler)
                delete(app.low_cutoff_ruler);
            end
            if ~isempty(app.high_cutoff_ruler)
                delete(app.high_cutoff_ruler);
            end
            % Plot rulers according to filter type
            if AnyFilter
                hold(app.MagVSFreq, 'on');
                app.low_cutoff_ruler = plot(app.MagVSFreq, ones(1,2) .* app.LowCutoffEditField.Value, app.MagVSFreq.YLim, 'Color', app.ColorCutoffFrequency , "DisplayName", "First Cutoff Frequency");
                if TwoCutoffs
                    hold(app.MagVSFreq, 'on');
                    app.high_cutoff_ruler = plot(app.MagVSFreq, ones(1,2) .*app.SecondCutoffEditField.Value,app.MagVSFreq.YLim, 'Color', app.ColorCutoffFrequency , "DisplayName", "Second Cutoff Frequency");
                end
            end
            % Update sliders
            app.LowCutoffSlider.Value = app.LowCutoffEditField.Value;
            app.SecondCutoffSlider.Value = app.SecondCutoffEditField.Value;
        end
        
        
        function PlotCutoff(app)
            % Plot rulers to help visualize cutoff frequencies and update sliders
            % Get flags for filter with 1 cutoff frequency or 2
            FilterType = app.FilterTypeDropDown.Value;
            AnyFilter = FilterType ~= "No Filter"; % Any filter has at least one cutoff frequency
            TwoFreqs = FilterType == "Band Pass Filter" || FilterType == "Band Stop Filter"; % Only BP&BS have 2 cutoff frequencies
            
            % Delete old rulers from graph if exist
            if ~isempty(app.low_cutoff_ruler)
                delete(app.low_cutoff_ruler);
            end
            if ~isempty(app.high_cutoff_ruler)
                delete(app.high_cutoff_ruler);
            end
            % Plot rulers according to filter type
            if AnyFilter
                hold(app.MagVSFreq, 'on');
                app.low_cutoff_ruler = plot(app.MagVSFreq, ones(1,2) .*app.LowCutoffEditField.Value, app.MagVSFreq.YLim, 'Color', app.ColorCutoffFrequency , "DisplayName", "First Cutoff Frequency");
            end
            if TwoFreqs
               hold(app.MagVSFreq, 'on');
               app.high_cutoff_ruler = plot(app.MagVSFreq, ones(1,2) .*app.SecondCutoffEditField.Value,app.MagVSFreq.YLim, 'Color', app.ColorCutoffFrequency, "DisplayName", "Second Cutoff Frequency");
            end
            
            % Update sliders
            app.LowCutoffSlider.Value = app.LowCutoffEditField.Value;
            app.SecondCutoffSlider.Value = app.SecondCutoffEditField.Value;
        end
        
        function PlotAll(app)
            PlotSignalTimeDomain(app);
            PlotSignalFrequencyDomain(app);
            PlotFilter(app);
            PlotCutoff(app);
        end

        function Run(app)
            CreateSignalWave(app);
            ChooseFilterType(app);
            PlotAll(app);
        end


       function PlotMonoSignalFreqDomain(app, Magnitude, FilteredMagnitude, Phase, FilteredPhase)
                plot(app.MagVSFreq, app.theta, Magnitude,'Color', app.ColorSignalUnfiltered , 'DisplayName', 'Signal (Unfiltered)');
                plot(app.PhaseVSFreq, app.theta, Phase, 'Color', app.ColorSignalUnfiltered ,'DisplayName', 'Signal (Unfiltered)');
                if all(~isnan(app.f_signal)) % Filtered signal exsits
                    hold(app.MagVSFreq, 'on');
                    hold(app.PhaseVSFreq, 'on');
                    plot(app.MagVSFreq, app.theta, FilteredMagnitude, 'Color', app.ColorSignalFiltered , 'DisplayName', 'Signal (Filtered)');
                    plot(app.PhaseVSFreq, app.theta, FilteredPhase, 'Color', app.ColorSignalFiltered , 'DisplayName', 'Signal (Filtered)');
                end

        end
        
        function PlotStereoSignalFreqDomain(app, Magnitude, FilteredMagnitude, Phase, FilteredPhase)
                plot(app.MagVSFreq, app.theta, Magnitude(1,:), 'DisplayName', 'Left Signal (Unfiltered)');
                plot(app.PhaseVSFreq, app.theta, Phase(1,:), 'DisplayName', 'Left Signal (Unfiltered)');
                hold(app.MagVSFreq, 'on');
                hold(app.PhaseVSFreq, 'on');
                plot(app.MagVSFreq, app.theta, Magnitude(2,:), 'DisplayName', 'Right Signal (Unfiltered)');
                plot(app.PhaseVSFreq, app.theta, Phase(2,:), 'DisplayName', 'Right Signal (Unfiltered)');
                if all(~isnan(app.f_signal)) % Filtered signal exsits
                    hold(app.MagVSFreq, 'on');
                    hold(app.PhaseVSFreq, 'on');
                    plot(app.MagVSFreq, app.theta, FilteredMagnitude(1,:), 'DisplayName', 'Left Signal (Filtered)');
                    plot(app.PhaseVSFreq, app.theta, FilteredPhase(1,:), 'DisplayName', 'Left Signal (Filtered)');
                    hold(app.MagVSFreq, 'on');
                    hold(app.PhaseVSFreq, 'on');
                    plot(app.MagVSFreq, app.theta, FilteredMagnitude(2,:), 'DisplayName', 'Right Signal (Filtered)');
                    plot(app.PhaseVSFreq, app.theta, FilteredPhase(2,:), 'DisplayName', 'Right Signal (Filtered)');
                end
        end


    end


    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            ScreenAdjustments(app);
            ResetParameters(app);
            CreateSignalWave(app);
            ChooseFilterType(app);
            PlotSignalTimeDomain(app);


        end

        % Button pushed function: CloseButton
        function CloseButtonPushed(app, event)
            delete(app);
        end

        % Value changed function: DutyCycleEditField
        function DutyCycleEditFieldValueChanged(app, event)
            value = app.DutyCycleEditField.Value;
            app.DutyCycleKnob.Value = value;
            Run(app);
        end

        % Value changed function: DutyCycleKnob
        function DutyCycleKnobValueChanged(app, event)
            value = app.DutyCycleKnob.Value;
            app.DutyCycleEditField.Value = value;
            DutyCycleEditFieldValueChanged(app);
        end

        % Button pushed function: InsertaudiofileButton
        function InsertaudiofileButtonPushed(app, event)

            [filename, path] = uigetfile(app.AudioFormats, "Select audio file");
            file = [path filename];
            if filename == 0 % File not chosen, show error box
                uiwait(errordlg("File not selected"));
                return;
            end
            [~, ~, fExt] = fileparts(filename); % Get file extension to determine type
            fExt = "*" + fExt;
            
            if any(ismember(fExt, app.AudioFormats))
                switch fExt
                    case "*.mid"
                        try % Try loading the MIDI file
                            midi = readmidi(filename);
                            % synthesize with FM-synthesis.
                            % (y = audio samples.  Fs = sample rate.  Here, uses default 44.1k.)
                            [app.signal,app.sampling_f] = midi2audio(midi); 
                            Title = "Amplitude VS Time (Signal Type = MIDI Audio ,Fs = " + num2str(app.sampling_f) + " Hz)"; % Title
                            app.signal = app.signal';
                            app.f_signal = app.signal * NaN;
                            app.duration = length(app.signal) / app.sampling_f;
                            app.time = [0 : 1/app.sampling_f : app.duration - 1/app.sampling_f];
                            title(app.AmpVSTime, Title)
                            PlotSignalTimeDomain(app);           % Plot new signal in time & frequency domains
                        catch % Invalid file or corrupted file. Show error message
                            uiwait(errordlg("Invalid or corrupted MIDI file." + newline + "Try choosing a different MIDI file"));
                            return;
                        end
                    otherwise
                        try % Try loading the audio file
                            [app.signal, app.sampling_f] = audioread(file);
                            Title = "Amplitude VS Time (Signal Type = External Audio ,Fs = " + num2str(app.sampling_f) + " Hz)"; % Title
                        catch % Invalid file or corrupted file. Show error message
                            uiwait(errordlg("Invalid or corrupted file." + newline + "Try choosing a different file"));
                            return;
                        end
                end  
                                    
                    else % Illegal file type, show error box
                    uiwait(errordlg("File not unsupported" + newline + "Try choosing a different file"));
                    return;
            end

                % Save signal parameters
    
                      app.signal = app.signal';

                compress = true;
                
                     if size(app.signal, 1) > 2 %Convert multi-channel audio to stereo
                        while compress
                            try
                                app.signal = reshape(app.signal, 2, [] , 1);
                                compress = false;
                            catch
                                app.signal = app.signal(1:100:end) % Compress signal by keeping every 10'th cell and delete all the rest; 
                            end
                        end

                     end
                app.f_signal = app.signal * NaN;
                app.duration = length(app.signal) / app.sampling_f;
                app.time = [0 : 1/app.sampling_f : app.duration - 1/app.sampling_f];
                title(app.AmpVSTime, Title)
                PlotSignalTimeDomain(app);           % Plot new signal in time & frequency domains
                    % Update GUI
                    title(app.AmpVSTime, Title)
                if app.FilterTypeDropDown.Value ~= "No Filter"
                    PlotSignalFrequencyDomain(app);
                end


        end

        % Button pushed function: SaveSignalButton
        function SaveSignalButtonPushed(app, event)
            % Save filtered/unfiltered signal to file
            % Get file type and path 
            [filename, path] = uiputfile(app.SaveFormats, "Choose a path and name", "Untitled signal");
            if filename == 0 % File not chosen, show error box
                beep;
                uiwait(errordlg("Save path not selected" + newline + "Please try again"));
                return;
            end
            file = [path  filename];
            [~, ~, fExt] = fileparts(filename);
            if lower(fExt) == '.mid'
                app.signal = .95.*app.signal./max(abs(app.signal));
            end
            if any(isnan(app.f_signal)) % If no filtered signal present, save source signal
                    audiowrite(file, app.signal', app.sampling_f);
            else % If filtered signal present, save filtered signal
                    audiowrite(file, app.f_signal', app.sampling_f);
             end


        end

        % Value changed function: FilterTypeDropDown
        function FilterTypeDropDownValueChanged(app, event)
            ChooseFilterType(app);

        end

        % Value changed function: LowCutoffEditField
        function LowCutoffEditFieldValueChanged(app, event)
            value = app.LowCutoffEditField.Value;
            app.LowCutoffSlider.Value = value;
            LowCutoffSliderValueChanged(app);
            

        end

        % Button pushed function: RecordButton
        function RecordButtonPushed(app, event)
        app.sampling_f = 44100; %sampling frequency for recording
        a= audiorecorder(app.sampling_f,8,1);
        uiwait(msgbox("Press OK and start speaking (for " +  app.RecordtimeEditField.Value + " seconds)"));
        recordblocking(a,app.RecordtimeEditField.Value);
        uiwait(msgbox("End of recording"));        
        app.signal = getaudiodata(a);

        if size(app.signal, 1) > 2 %Convert multi-channel audio to stereo
            app.signal = reshape(app.signal, 2, [] , 1);
        end
        app.signal = app.signal * (10 ^ (app.AmpVal.Value / 20)); %automatically amplify signal
        app.f_signal = app.signal * NaN;
        app.duration = length(app.signal) / app.sampling_f;
        app.time = [0 : 1/app.sampling_f : app.duration - 1/app.sampling_f];

        Title = "Amplitude VS Time (Signal Type = Manual Recording ,Fs = " + num2str(app.sampling_f) + " Hz)"; % Title
        title(app.AmpVSTime, Title);
        app.AmpVSTime.XLim = [0 app.RecordtimeEditField.Value;];

        PlotSignalTimeDomain(app);           % Plot new signal in time & frequency domains
        ChooseFilterType(app);


        end

        % Button pushed function: ResetButton
        function ResetButtonPushed(app, event)
        
            startupFcn(app);

        end

        % Button pushed function: PlayButton
        function PlayButtonPushed(app, event)
            if isnan(app.f_signal) 
            try 
                sound(app.signal,app.sampling_f);
            catch
            end
            else
                try 
                    sound(app.f_signal,app.sampling_f);
                catch
                end
            end

        end

        % Value changed function: LowCutoffSlider
        function LowCutoffSliderValueChanged(app, event)
            value = app.LowCutoffSlider.Value;
            if value > app.SecondCutoffSlider.Value
                app.LowCutoffSlider.Value = app.SecondCutoffSlider.Value;
                app.SecondCutoffSlider.Value = value;               
            end
            app.SecondCutoffEditField.Value = app.SecondCutoffSlider.Value
            app.LowCutoffEditField.Value = app.LowCutoffSlider.Value
            FilterTypeDropDownValueChanged(app);
        end

        % Callback function
        function PassbandEditFieldValueChanged(app, event)
            value = app.LowCutoffEditField.Value;
            app.LowCutoffSlider.Value = value;
            FilterTypeDropDownValueChanged(app);
        end

        % Value changed function: AmplitudeEditField
        function AmplitudeEditFieldValueChanged(app, event)
            Run(app);
        end

        % Value changed function: FrequencyEditField
        function FrequencyEditFieldValueChanged(app, event)
            Run(app);
            
        end

        % Value changed function: SamplingFrequencyEditField
        function SamplingFrequencyEditFieldValueChanged(app, event)
            
            if app.DurationEditField.Value < 1/app.SamplingFrequencyEditField.Value
                app.DurationEditField.Value = 1/app.SamplingFrequencyEditField.Value;
            end
            value = app.DurationEditField.Value;
            app.DurationEditField.Limits = [1/app.SamplingFrequencyEditField.Value inf]; %update duration limits
            app.sampling_f = value;
            Run(app);
        end

        % Value changed function: FilterOrderEditField
        function FilterOrderEditFieldValueChanged(app, event)
            Run(app);
            
        end

        % Value changed function: SecondCutoffEditField
        function SecondCutoffEditFieldValueChanged(app, event)
            value = app.SecondCutoffEditField.Value;
            app.SecondCutoffSlider.Value = value;
            SecondCutoffSliderValueChanged(app);
        end

        % Button pushed function: ApplyButton
        function ApplyButtonPushed(app, event)
            if app.FirstClick
                app.FirstClick = false;
                app.f_signal = filter(app.filtercoef, [1], app.signal')'; % Filter the signal
            else
                app.f_signal = filter(app.filtercoef, [1], app.f_signal')'; % Filter the filtered signal
            end
            PlotAll(app);

        end

        % Button pushed function: StopButton
        function StopButtonPushed(app, event)
            clear sound;
        end

        % Button pushed function: Minus
        function MinusPushed(app, event)
            app.signal = app.signal / (10 ^ (app.AmpVal.Value / 20));
            if ~isnan(app.f_signal) %filter signal only if current filtered signal exists.
                app.f_signal = app.f_signal * nan; %reset filtered signal
                app.f_signal = filter(app.filtercoef, [1], app.signal')'; % Filter the filtered signal
            end
            PlotSignalTimeDomain(app);
            ChooseFilterType(app);


        end

        % Button pushed function: Plus
        function PlusButtonPushed(app, event)
            app.signal = app.signal * (10 ^ (app.AmpVal.Value / 20));
            if ~isnan(app.f_signal) %filter signal only if current filtered signal exists.
                app.f_signal = app.f_signal * nan; %reset filtered signal
                app.f_signal = filter(app.filtercoef, [1], app.signal')'; % Filter the filtered signal
            end
            PlotSignalTimeDomain(app);
            ChooseFilterType(app);
        end

        % Value changed function: SecondCutoffSlider
        function SecondCutoffSliderValueChanged(app, event)
            value = app.SecondCutoffSlider.Value;
            if value < app.LowCutoffSlider.Value
                app.SecondCutoffSlider.Value = app.LowCutoffSlider.Value;
                app.LowCutoffSlider.Value = value;
            end
            app.SecondCutoffEditField.Value = app.SecondCutoffSlider.Value
            app.LowCutoffEditField.Value = app.LowCutoffSlider.Value
            FilterTypeDropDownValueChanged(app);
            
        end

        % Value changed function: DurationEditField
        function DurationEditFieldValueChanged(app, event)
            Run(app);
        end

        % Value changed function: SignalTypeBox
        function SignalTypeBoxValueChanged(app, event)
            Run(app);
            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.SignalGenerator = uifigure('Visible', 'off');
            app.SignalGenerator.Name = 'Signal Properties';
            app.MagVSFreq = uiaxes(app.SignalGenerator);
            title(app.MagVSFreq, 'Magnitude VS Normalized Frequency')
            xlabel(app.MagVSFreq, 'Normalized Frequency [π * Hz]')
            ylabel(app.MagVSFreq, 'Magnitude [dB]')
            zlabel(app.MagVSFreq, 'Z')
            app.PhaseVSFreq = uiaxes(app.SignalGenerator);
            title(app.PhaseVSFreq, 'Phase VS Normalized Frequency')
            xlabel(app.PhaseVSFreq, 'Normalized Frequency [π * Hz]')
            ylabel(app.PhaseVSFreq, 'Phase [Degrees]')
            zlabel(app.PhaseVSFreq, 'Z')
            app.AmpVSTime = uiaxes(app.SignalGenerator);
            title(app.AmpVSTime, 'Amplitude VS Time')
            xlabel(app.AmpVSTime, 'Time [seconds]')
            ylabel(app.AmpVSTime, 'Amplitude')
            zlabel(app.AmpVSTime, 'Z')
            app.CloseButton = uibutton(app.SignalGenerator, 'push');
            app.CloseButton.ButtonPushedFcn = createCallbackFcn(app, @CloseButtonPushed, true);
            app.CloseButton.Text = 'Close';
            app.InsertaudiofileButton = uibutton(app.SignalGenerator, 'push');
            app.InsertaudiofileButton.ButtonPushedFcn = createCallbackFcn(app, @InsertaudiofileButtonPushed, true);
            app.InsertaudiofileButton.Text = 'Insert audio file';
            app.SaveSignalButton = uibutton(app.SignalGenerator, 'push');
            app.SaveSignalButton.ButtonPushedFcn = createCallbackFcn(app, @SaveSignalButtonPushed, true);
            app.SaveSignalButton.Text = 'Save Signal';
            app.DCMenu = uipanel(app.SignalGenerator);
            app.DutyCycleInf = uilabel(app.DCMenu);
            app.DutyCycleInf.Text = '(Choose a number between 0 to 100)';
            app.PercentageLabel = uilabel(app.DCMenu);
            app.PercentageLabel.Text = '[%]';
            app.DutyCycleEditFieldLabel = uilabel(app.DCMenu);
            app.DutyCycleEditFieldLabel.Text = 'Duty Cycle';
            app.DutyCycleEditField = uieditfield(app.DCMenu, 'numeric');
            app.DutyCycleEditField.Limits = [0 100];
            app.DutyCycleEditField.RoundFractionalValues = 'on';
            app.DutyCycleEditField.ValueChangedFcn = createCallbackFcn(app, @DutyCycleEditFieldValueChanged, true);
            app.DutyCycleEditField.Value = 50;
            app.DutyCycleKnobLabel = uilabel(app.DCMenu);
            app.DutyCycleKnobLabel.Text = 'Duty Cycle';
            app.DutyCycleKnob = uiknob(app.DCMenu, 'continuous');
            app.DutyCycleKnob.MajorTicks = [0 10 20 30 40 50 60 70 80 90 100];
            app.DutyCycleKnob.MajorTickLabels = {'0%', '10%', '20%', '30%', '40%', '50%', '60%', '70%', '80%', '90%', '100%'};
            app.DutyCycleKnob.ValueChangedFcn = createCallbackFcn(app, @DutyCycleKnobValueChanged, true);
            app.DutyCycleKnob.MinorTicks = [0 10 20 30 40 50 60 70 80 90 100];
            app.DutyCycleKnob.Value = 50;
            app.Image = uiimage(app.SignalGenerator);
            app.RecordButton = uibutton(app.SignalGenerator, 'push');
            app.RecordButton.ButtonPushedFcn = createCallbackFcn(app, @RecordButtonPushed, true);
            app.RecordButton.Text = 'Record';
            app.FrequencyEditFieldLabel = uilabel(app.SignalGenerator);
            app.FrequencyEditFieldLabel.Text = '[Hz]';
            app.FrequencyEditFieldLabel_2 = uilabel(app.SignalGenerator);
            app.FrequencyEditFieldLabel_2.Text = '';
            app.FrequencyEditFieldLabel_3 = uilabel(app.SignalGenerator);
            app.FrequencyEditFieldLabel_3.Text = '[Hz]';
            app.FrequencyEditFieldLabel_4 = uilabel(app.SignalGenerator);
            app.FrequencyEditFieldLabel_4.Text = '[sec]';
            app.ResetButton = uibutton(app.SignalGenerator, 'push');
            app.ResetButton.ButtonPushedFcn = createCallbackFcn(app, @ResetButtonPushed, true);
            app.ResetButton.Text = 'Reset';
            app.ChooseawaveformListBoxLabel = uilabel(app.SignalGenerator);
            app.ChooseawaveformListBoxLabel.Text = 'Choose a waveform:';
            app.SignalTypeBox = uilistbox(app.SignalGenerator);
            app.SignalTypeBox.Items = {'Sin', 'Sawtooth', 'Square', 'Triangle'};
            app.SignalTypeBox.ValueChangedFcn = createCallbackFcn(app, @SignalTypeBoxValueChanged, true);
            app.SignalTypeBox.Value = 'Sin';
            app.FrequencyFcEditFieldLabel = uilabel(app.SignalGenerator);
            app.FrequencyFcEditFieldLabel.Text = 'Frequency [Fc]';
            app.FrequencyEditField = uieditfield(app.SignalGenerator, 'numeric');
            app.FrequencyEditField.Limits = [1 20000];
            app.FrequencyEditField.ValueChangedFcn = createCallbackFcn(app, @FrequencyEditFieldValueChanged, true);
            app.FrequencyEditField.Value = 10;
            app.AmplitudeAEditFieldLabel = uilabel(app.SignalGenerator);
            app.AmplitudeAEditFieldLabel.Text = 'Amplitude [A]';
            app.AmplitudeEditField = uieditfield(app.SignalGenerator, 'numeric');
            app.AmplitudeEditField.Limits = [0.1 Inf];
            app.AmplitudeEditField.ValueChangedFcn = createCallbackFcn(app, @AmplitudeEditFieldValueChanged, true);
            app.AmplitudeEditField.Value = 10;
            app.SamplingFrequencyFsEditFieldLabel = uilabel(app.SignalGenerator);
            app.SamplingFrequencyFsEditFieldLabel.Text = 'Sampling Frequency [Fs]';
            app.SamplingFrequencyEditField = uieditfield(app.SignalGenerator, 'numeric');
            app.SamplingFrequencyEditField.Limits = [1000 Inf];
            app.SamplingFrequencyEditField.RoundFractionalValues = 'on';
            app.SamplingFrequencyEditField.ValueChangedFcn = createCallbackFcn(app, @SamplingFrequencyEditFieldValueChanged, true);
            app.SamplingFrequencyEditField.Value = 1000;
            app.DurationtEditFieldLabel = uilabel(app.SignalGenerator);
            app.DurationtEditFieldLabel.Text = 'Duration [t]';
            app.DurationEditField = uieditfield(app.SignalGenerator, 'numeric');
            app.DurationEditField.Limits = [0.1 Inf];
            app.DurationEditField.ValueChangedFcn = createCallbackFcn(app, @DurationEditFieldValueChanged, true);
            app.DurationEditField.Value = 1;
            app.FilterTypeDropDownLabel = uilabel(app.SignalGenerator);
            app.FilterTypeDropDownLabel.Text = 'Filter Type';
            app.FilterTypeDropDown = uidropdown(app.SignalGenerator);
            app.FilterTypeDropDown.Items = {'No Filter', 'Low Pass Filter', 'High Pass Filter', 'Band Pass Filter', 'Band Stop Filter'};
            app.FilterTypeDropDown.ValueChangedFcn = createCallbackFcn(app, @FilterTypeDropDownValueChanged, true);
            app.FilterTypeDropDown.Value = 'No Filter';
            app.PlayButton = uibutton(app.SignalGenerator, 'push');
            app.PlayButton.ButtonPushedFcn = createCallbackFcn(app, @PlayButtonPushed, true);
            app.PlayButton.Text = 'Play';
            app.FilterSettings = uipanel(app.SignalGenerator);
            app.FilterSettings.Visible = 'off';
            app.fH = uipanel(app.FilterSettings);
            app.fH.Visible = 'off';
            app.SecondCutoffEditField = uieditfield(app.fH, 'numeric');
            app.SecondCutoffEditField.Limits = [0.02 0.98];
            app.SecondCutoffEditField.ValueDisplayFormat = '%11.2g';
            app.SecondCutoffEditField.ValueChangedFcn = createCallbackFcn(app, @SecondCutoffEditFieldValueChanged, true);
            app.SecondCutoffEditField.Value = 0.7;
            app.SecondCutoffFrequencyLabel = uilabel(app.fH);
            app.SecondCutoffFrequencyLabel.Text = {'2nd Cutoff Frequency'; ''};
            app.SecondCutoffSlider = uislider(app.fH);
            app.SecondCutoffSlider.Limits = [0.02 0.98];
            app.SecondCutoffSlider.MajorTicks = [0.02 0.18 0.34 0.5 0.66 0.82 0.98];
            app.SecondCutoffSlider.MajorTickLabels = {'0.02', '0.18', '0.34', '0.5', '0.66', '0.82', '0.98'};
            app.SecondCutoffSlider.ValueChangedFcn = createCallbackFcn(app, @SecondCutoffSliderValueChanged, true);
            app.SecondCutoffSlider.MinorTicks = [0.02 0.04 0.06 0.08 0.1 0.12 0.14 0.16 0.18 0.2 0.22 0.24 0.26 0.28 0.3 0.32 0.34 0.36 0.38 0.4 0.42 0.44 0.46 0.48 0.5 0.52 0.54 0.56 0.58 0.6 0.62 0.64 0.66 0.68 0.7 0.72 0.74 0.76 0.78 0.8 0.82 0.84 0.86 0.88 0.9 0.92 0.94 0.96 0.98];
            app.SecondCutoffSlider.Value = 0.02;
            app.FilterOrderEditField = uieditfield(app.FilterSettings, 'numeric');
            app.FilterOrderEditField.Limits = [1 Inf];
            app.FilterOrderEditField.RoundFractionalValues = 'on';
            app.FilterOrderEditField.ValueChangedFcn = createCallbackFcn(app, @FilterOrderEditFieldValueChanged, true);
            app.FilterOrderEditField.Value = 1;
            app.LowCutoffEditField = uieditfield(app.FilterSettings, 'numeric');
            app.LowCutoffEditField.Limits = [0.02 0.98];
            app.LowCutoffEditField.ValueDisplayFormat = '%11.2g';
            app.LowCutoffEditField.ValueChangedFcn = createCallbackFcn(app, @LowCutoffEditFieldValueChanged, true);
            app.LowCutoffEditField.Value = 0.5;
            app.LowCutoffSlider = uislider(app.FilterSettings);
            app.LowCutoffSlider.Limits = [0.02 0.98];
            app.LowCutoffSlider.MajorTicks = [0.02 0.18 0.34 0.5 0.66 0.82 0.98];
            app.LowCutoffSlider.ValueChangedFcn = createCallbackFcn(app, @LowCutoffSliderValueChanged, true);
            app.LowCutoffSlider.MinorTicks = [0.02 0.04 0.06 0.08 0.1 0.12 0.14 0.16 0.18 0.2 0.22 0.24 0.26 0.28 0.3 0.32 0.34 0.36 0.38 0.4 0.42 0.44 0.46 0.48 0.5 0.52 0.54 0.56 0.58 0.6 0.62 0.64 0.66 0.68 0.7 0.72 0.74 0.76 0.78 0.8 0.82 0.84 0.86 0.88 0.9 0.92 0.94 0.96 0.98];
            app.LowCutoffSlider.Value = 0.5;
            app.ApplyButton = uibutton(app.FilterSettings, 'push');
            app.ApplyButton.ButtonPushedFcn = createCallbackFcn(app, @ApplyButtonPushed, true);
            app.ApplyButton.Text = 'Apply';
            app.FilterOrderEditFieldLabel = uilabel(app.FilterSettings);
            app.FilterOrderEditFieldLabel.Text = 'Filter Order';
            app.CutoffFrequencyLabel = uilabel(app.FilterSettings);
            app.CutoffFrequencyLabel.Text = 'Cutoff Frequency';
            app.RecordtimeEditFieldLabel = uilabel(app.SignalGenerator);
            app.RecordtimeEditFieldLabel.Text = 'Record time';
            app.RecordtimeEditField = uieditfield(app.SignalGenerator, 'numeric');
            app.RecordtimeEditField.Limits = [1 60];
            app.RecordtimeEditField.Value = 3;
            app.secLabel = uilabel(app.SignalGenerator);
            app.secLabel.Text = '[sec]';
            app.StopButton = uibutton(app.SignalGenerator, 'push');
            app.StopButton.ButtonPushedFcn = createCallbackFcn(app, @StopButtonPushed, true);
            app.StopButton.Text = 'Stop';
            app.Minus = uibutton(app.SignalGenerator, 'push');
            app.Minus.ButtonPushedFcn = createCallbackFcn(app, @MinusPushed, true);
            app.Minus.Text = '-';
            app.AmpVal = uieditfield(app.SignalGenerator, 'numeric');
            app.AmpVal.Value = 20;
            app.Plus = uibutton(app.SignalGenerator, 'push');
            app.Plus.ButtonPushedFcn = createCallbackFcn(app, @PlusButtonPushed, true);
            app.Plus.Text = '+';
            app.AddSubrstractAmplitudedBLabel = uilabel(app.SignalGenerator);
            app.AddSubrstractAmplitudedBLabel.Text = 'Add / Subrstract Amplitude [dB]';
            % Show the figure after all components are created
            app.SignalGenerator.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = SignalProperties

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.SignalGenerator)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.SignalGenerator)
        end
    end
end